classdef UNIdecod_with_function_temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UNIdecodLabel                matlab.ui.control.Label
        LoadFolderButton             matlab.ui.control.Button
        SelectonefileormoreLabel     matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        GOButton                     matlab.ui.control.Button
        FolderPathTxt                matlab.ui.control.Label
        TimewindowLabel              matlab.ui.control.Label
        toEditFieldLabel             matlab.ui.control.Label
        toEditField                  matlab.ui.control.NumericEditField
        fromEditField_2Label         matlab.ui.control.Label
        fromEditField_2              matlab.ui.control.NumericEditField
        InformationLabel             matlab.ui.control.Label
        DecodingButtonGroup          matlab.ui.container.ButtonGroup
        SVMJRKButton                 matlab.ui.control.RadioButton
        XdawnRiemButton              matlab.ui.control.RadioButton
        ERPcovRiemButton             matlab.ui.control.RadioButton
        HenkelRiemButton             matlab.ui.control.RadioButton
        StepLabel                    matlab.ui.control.Label
        StepEditField                matlab.ui.control.NumericEditField
        msLabel                      matlab.ui.control.Label
        ClickHereButton              matlab.ui.control.Button
        ChannelstoignoreLabel        matlab.ui.control.Label
        ChannelstoignoreEditField    matlab.ui.control.EditField
        Condition1Label              matlab.ui.control.Label
        Condition1EditField          matlab.ui.control.EditField
        Condition2Label              matlab.ui.control.Label
        Condition2EditField          matlab.ui.control.EditField
        exampleCondition11Condition224Label  matlab.ui.control.Label
        GeneralizationCheckBox       matlab.ui.control.CheckBox
        Condition1genLabel           matlab.ui.control.Label
        Condition1genEditField       matlab.ui.control.EditField
        Condition2genEditFieldLabel  matlab.ui.control.Label
        Condition2genEditField       matlab.ui.control.EditField
        LaunchEEGlabButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        DataFolder % DataFolderPath
        Set_files
        file_list
        t_min
        t_max
        
        data
        bads
        time_window
        condi_1
        condi_2
        decoding_type
        app_path
        eye_channels
        data_folder
        step
        results
        time2
    end
    
    methods (Access = public)
        
        %%------------------------------------SIMPLE DISPLAY TO TEST---------------------------------------%%
        
        
        function results = display_test(app, data, time_window, step, condi_1, condi_2, decoding_type, eye_channels)
            disp('time window :'); disp(app.time_window);
            disp('selected data :'); disp(app.data);
            disp('step :'); disp(app.step);
            disp('condition 1 :'); disp(app.condi_1);
            disp('condition 2 :'); disp(app.condi_2);
            disp('type of decoding :'); disp(app.decoding_type);
            disp('eye channels :'); disp(app.eye_channels);
            disp('bads marked as :'); disp(app.bads);
        end
        
        
        %%--------------------------------------------MAIN FUNCTION-----------------------------------------%%
        
        
        function [results, time2] = decoding_main(app, data, time_window, step, condi_1, condi_2, decoding_type,app_path, eye_channels, data_folder)
            
            %% Variables initialization %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            for i = 1:length(data)
                
                tw= time_window;
                ttz=[];
                ttlabelscell=[];
                ttlabelscond=[];
                
                %% Data extraction and modification %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %for f=1:length(list)
                filename=data(i);
                EEG=pop_loadset(filename);
                %EEG.data=(eye_channels,:,:)=[]; % remove eye channels
                save('test.mat')
                %% Remove bad trials or when the baby hasnt looked
                if isfield(EEG.event,'look')
                    EEG = pop_selectevent( EEG, 'look',1,'deleteevents','off','deleteepochs','on','invertepochs','off'); %remove look=0
                end
                if isfield(EEG.event,'bad')
                    EEG = pop_selectevent( EEG, 'bad',0,'deleteevents','off','deleteepochs','on','invertepochs','off'); %remove badtrials
                end
                
                %% Shorten time window
                tws=dsearchn(EEG.times',tw');
                EEG.data=EEG.data(:,tws(1):tws(2),:);
                EEG.times=EEG.times(tws(1):tws(2));
                
                %% Extract data matrix
                eeg_data=EEG.data;
                
                %% Correct in case of assymetry
                
                cell = cell2mat(arrayfun(@(x) x.eventcell{find(cell2mat(x.eventlatency)==0)},EEG.epoch, 'uniformoutput', false));
                cond = cell2mat(arrayfun(@(x) x.eventcond{find(cell2mat(x.eventlatency)==0)},EEG.epoch, 'uniformoutput', false));
                lat=cell2mat(arrayfun(@(x) find(cell2mat(x.eventlatency)==0),EEG.epoch, 'uniformoutput', false)); % recover which event was the 0 event
                if length(lat) > length(cell)
                    b=arrayfun(@(x) find(cell2mat(x.eventlatency)==0),EEG.epoch, 'uniformoutput', false);
                    for bi=1:length(b)
                        if size(b{bi},2)>1
                            EEG.epoch(bi).eventlatency{b{bi}(2)}=[];
                        end
                    end
                    lat=cell2mat(arrayfun(@(x) find(cell2mat(x.eventlatency)==0),EEG.epoch, 'uniformoutput', false));
                end
                
                if length(cond)~=length(cell)
                    var=[];
                    ii=0;
                    for i=1:length(cell)
                        if isempty(EEG.epoch(i).eventcond{lat(i)})
                            ii=ii+1;
                            var(ii)=i;
                        end
                    end
                    for i=1:length(var)
                        EEG.epoch(var(i)).eventcond{lat(var(i))}=-100;
                    end
                    cond = cell2mat(arrayfun(@(x) x.eventcond{find(cell2mat(x.eventlatency)==0)},EEG.epoch, 'uniformoutput', false));
                end
                
                %% Select trials to train the classifier %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                clear good z x
                cond=cond(find(cond~=-100));
                good=find(cond~=-100);
                z=eeg_data(:,:,good');
                z = permute(z,[3 1 2]);
                labelscond=cond(good);
                labelscell=cell(good);
                ttz=cat(1,ttz, z);
                ttlabelscell=[ttlabelscell labelscell];
                ttlabelscond=[ttlabelscond labelscond];
            end
            
            %% Creating two stimuli classes (1 and 2), stored in vector L %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            orttlabelscond=ttlabelscond;
            good=find(ttlabelscell ==1); %init = 3
            % good=find(ttlabelscell ~=2);
            ttlabelscond=orttlabelscond(good);
            ttz=ttz(good,:,:);
            
            cond_matrix_1={};
            cond_matrix_2={};
            
            if length(app.condi_1)==1
                cond1=ttlabelscond==app.condi_1;
            else
                for i = 1:length(app.condi_1)
                    cond_matrix_1{i}=ttlabelscond == app.condi_1(1,i);
                end
                cond1=cat(1,cond_matrix_1{:});
                cond1=sum(cond1);
            end
            
            if length(app.condi_2)==1
                cond2=ttlabelscond==app.condi_2;
            else
                for i = 1:length(app.condi_2)
                    cond_matrix_2{i}=ttlabelscond == app.condi_2(1,i);
                end
                cond2=cat(1,cond_matrix_2{:});
                cond2=sum(cond2);
            end
            
            condtestT={cond1,cond2};
            
            [ntrials nchan nsamples] = size(ttz);
            L = zeros(ntrials,1);
            L(condtestT{1}==1) = 1;
            L(condtestT{2}==1) = 2;
            
            t=find(L~=0);
            L=L(t);
            
            %% Synchronizing data with time in x %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            save('X.mat')
            x=ttz(t,:,:);
            save('X.mat')
            time2=EEG.times(app.step:app.step:end);
            cfg = [];
            cfg.dims_tg = 0;
            data_folder=app.DataFolder;
            %% Running the decoding classifier : creating a result matrix %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            tic
            if decoding_type  == 'JR____'
                % results = jr_classify(app, x, L, cfg);
                results=jr_classify(app, x, L, cfg);
                probas = [];
                probas = squeeze(results.probas(:,:,:,:,1));
                save('test.mat')
                auc = colAUC(app, probas, results.y);
                
                % Plotting AUC
                
                figure;
                a=imagesc(time2, time2, auc, [0.3 0.8]); % plot area under the curve
                set(gca, 'ydir', 'normal');
                hold on
                plot(time2,time2, 'k--')
                axis square;colorbar
                
                mkdir('Results');
                cd('Results');
                saveas(a,'JR_decoding.jpg')
            
                
            else if decoding_type == 'Xdawn_'
                    results = call_riemaniann(app, x, L, decoding_type, data_folder, cfg);
                    
                else if decoding_type == 'ERPcov'
                        results = call_riemaniann(app, x, L, decoding_type, data_folder, cfg);
                        
                    elseif decoding_type == 'Henkel'
                        results = call_riemaniann(app, x, L, decoding_type, data_folder, cfg);
                    end
                end
                
            end
            toc
            
        end
        
        %%--------------------------------------------CLASSIF FUNCTIONS-----------------------------------%%
        
        % JR_CLASSIFY %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function results = jr_classify(app, Xm,y,cfg)
            
            disp('running jr_classify');
            % results = jr_classify(Xm,y,cfg)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % # Implementation of a multivariate pattern analysis based on  the
            % scikitlearn toolbox (http://scikit-learn.org/stable/). It reads a matlab
            % file containing :
            % #     Xm:      a matrix of trials x chans x timepoint.
            % #     y:       a vector indicating the class of each trial
            % # The classification algorithm is based on a support vector machine.
            % Optional inputs
            % cfg.dims = [1:nt]';
            % cfg.dims_tg = repmat(cfg.dims',length(cfg.dims),1)
            % # (c) Jean-Remi King 2012, jeanremi.king [at] gmail.com
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            if nargin == 2,                     cfg = [];                   end
            
            % get name;
            if isfield(cfg,'pypath'),            cd(cfg.pypath);             end
            if ~isfield(cfg,'namey'),           cfg.namey = 'default';      end % classification names for outputs
            if ~isfield(cfg,'path'),            cfg.path = [pwd '/'];       end % classification path for output
            if ~isfield(cfg,'saveX'),           cfg.saveX= true;            end % save a new data set
            if ~isfield(cfg,'savey'),           cfg.savey= true;            end % save a classification parameters
            if ~isfield(cfg,'load_results'),    cfg.load_results= true;     end %
            if ~isfield(cfg,'run_svm'),         cfg.run_svm= true;          end % save a new data set
            if ~isfield(cfg,'Xformat'),         cfg.Xformat= '.dat';        end %
            if ~isfield(cfg,'void'),            cfg.void = true;            end % provide continuous feedback
            
            if ischar(Xm)
                % default name identical to initial file
                
                if ~isfield(cfg,'nameX')
                    n = Xm;
                    p = strfind(n, '/');
                    if ~isempty(p), n = n((p(end)+1):end); end
                    p = strfind(n, cfg.Xformat);
                    if ~isempty(p), n = n(1:(p(end)-1)); end
                    cfg.nameX = n;
                end
                pathX = Xm;
            else
                if ~isfield(cfg,'nameX'),        cfg.nameX = 'default';    end
                pathX = [cfg.path cfg.nameX cfg.Xformat];
                cfg.Xdim = size(Xm);
            end
            
            %% save data
            if cfg.saveX && ~ischar(Xm) %|| ~exist(pathX, 'file')
                if cfg.void, disp(['save ' cfg.Xformat ': ' pathX '...']);end
                switch cfg.Xformat
                    case '.dat'
                        cfg.Xdim = size(Xm);
                        binsave(app, pathX, Xm);
                    case '.mat'
                        save(pathX, 'Xm');
                end
            end
            
            %% save classification parameters
            if cfg.savey
                if length(cfg.Xdim)==2
                    cfg.Xdim(3) = 1;
                end
                %         cfg.dims = [1:(length(cfg.time)-cfg.wsize+1)];
                %         cfg.dims_tg = cfg.dims';
                %         cfg.dims_tg = repmat(cfg.dims,length(cfg.dims),1);
                
                %-- diplay parameters
                if cfg.void
                    disp(cfg);
                end
                
                fields = fieldnames(cfg);
                %                 for f = 1:length(fields)
                %                     eval([fields{f} '=squeeze(cfg.' fields{f} ');']);
                %                 end
                if cfg.void, disp('Save classification parameters...'); end
                %                 save([cfg.path cfg.nameX '_' cfg.namey '_y.mat'], 'y', fields{:}, '-v7');
            end
            
            pathy = [cfg.path cfg.nameX '_' cfg.namey '_y.mat'];
            
            
            %% run classification
            if ~isfield(cfg,'n_jobs'), n_jobs = -1;else n_jobs = cfg.n_jobs;end
            script_path = which('skl_king_parallel.py');
            
            if isunix
                command = sprintf([...
                    'nice python "' script_path...
                    '" "' pathX ...
                    '" "' pathy ...
                    '" ' num2str(n_jobs)]);
            else
                command = [...
                    'python "' script_path...
                    '" "' pathX ...
                    '" "' pathy ...
                    '" ' num2str(1)];
                warning('no parallel computing is supported with windows');
            end
            
            disp(command);
            
            if cfg.void
                disp(command);
            end
            
            if cfg.run_svm
                if cfg.void, system(command);
                else evalc('system(command)'); end
            end
            results =[];
            
            %% load results
            if cfg.load_results
                path_results = [cfg.path cfg.nameX '_' cfg.namey '_results.mat'];
                if exist(path_results,'file')
                    if cfg.void
                        disp(['load ' path_results '...']);
                    end
                    results = load(path_results);
                else
                    disp([path_results 'does not exist.']);
                end
            else
                results = cfg;
            end
            if isfield(cfg,'path'),            cd(cfg.path);             end


            return
        end
            
            
        function binsave(app, filename, matrix)
            % binsave(filename, matrix)
            fid = fopen(filename,'w');
            fwrite(fid, matrix, 'float32');
            fclose(fid);
        end


        function m = nanmean(app, x, dim)
            
            %NANMEAN Mean value, ignoring NaNs.
            %   M = NANMEAN(X) returns the sample mean of X, treating NaNs as missing
            %   values.  For vector input, M is the mean value of the non-NaN elements
            %   in X.  For matrix input, M is a row vector containing the mean value of
            %   non-NaN elements in each column.  For N-D arrays, NANMEAN operates
            %   along the first non-singleton dimension.
            %
            %   NANMEAN(X,DIM) takes the mean along dimension DIM of X.
            %
            %   See also MEAN, NANMEDIAN, NANSTD, NANVAR, NANMIN, NANMAX, NANSUM.
            
            %   Copyright 1993-2004 The MathWorks, Inc.
            %   $Revision: 2.13.4.3 $  $Date: 2004/07/28 04:38:41 $
            
            % Find NaNs and set them to zero
            nans = isnan(x);
            x(nans) = 0;
            
            if nargin == 1 % let sum deal with figuring out which dimension to use
                % Count up non-NaNs.
                n = sum(~nans);
                n(n==0) = NaN; % prevent divideByZero warnings
                % Sum up non-NaNs, and divide by the number of non-NaNs.
                m = sum(x) ./ n;
            else
                % Count up non-NaNs.
                n = sum(~nans,dim);
                n(n==0) = NaN; % prevent divideByZero warnings
                % Sum up non-NaNs, and divide by the number of non-NaNs.
                m = sum(x,dim) ./ n;


            end
        end
        
        function [auc, labels, curve, se] = colAUC(app, X, y, alg)
            %COLAUC calculates Area under ROC curve (AUC) for a vector or for each
            % column of a matrix.
            %
            % SYNTAX:
            %   auc = colAUC(X, y)      - calculates AUC for each column of X
            %   auc = colAUC(X, y, alg) - calculates AUC for each column of X using
            %                             chosen algorithm
            %   [auc labels] = colAUC(X, y) - if "y" has more than 2 clases than AUC
            %             will be calculated for each pair of classes. Each row in
            %             "labels" array matches a row in "auc" array and returns
            %             labels of 2 classes compared, in the same format as input "y"
            %   colAUC(X, y) - if no output is requested than colAUC plots ROC curves
            %
            % INPUTS:
            %   X - 1D vector or 2D matrix (of feature columns and samples rows))
            %   y - Class labels for the X data samples in a form of a vector with one
            %       label for each row/sample of X. Can be either a cell array or
            %       numeric vector.
            %   alg - Algorithm to use: "ROC" integrates ROC curves, while "Wilcoxon"
            %       uses Wilcoxon Rank Sum Test to get the same results. Default
            %       "Wilcoxon" is faster. This argument is mostly provided for verification.
            %
            % DISCUSION
            %  AUC is a very useful measure of similarity between two classes
            %  measuring area under "Receiver Operating Characteristic" or ROC curve.
            %  In case of data with no ties all sections of ROC curve are either
            %  horizontal or vertical, in case of data with ties diagonal
            %  sections can also occur. Area under the ROC curve is calculated using
            %  trapz function. AUC is always in between 0.5 (two classes are
            %  statistically identical) and 1.0 (there is a threshold value that can
            %  achieve a perfect separation between the classes).
            %
            %  Area under ROC Curve (AUC) measure is very similar to Wilcoxon Rank Sum
            %  Test and Mann-Whitney U Test. If "alg" parameter is set to 'Wilcoxon'
            %  than AUC is calculated by performing that test and than canverting
            %  result
            %
            %  The main properties of this code are:
            %   * Ability to work with multi-dimensional data (X can have many columns).
            %   * Ability to work with multi-class datasets ("y" can have more
            %     than 2 different values).
            %   * Speed - this code was written to calculate AUC's of large number of
            %     features, fast.
            %   * Returned AUC is always bigger than 0.5, which is equivalent of
            %     testing for each feature colAUC(x,y) and colAUC(-x,y) and
            %     returning the value of the bigger one.
            %
            % OUTPUT:
            %   For 2 class problem colAUC returms a vector of AUC values one for each
            %   feature/column.
            %
            %   For multi class problem AUC will be calculated for all combinations of
            %   labels. All n!/((n-2)! 2!) = n(n-1)/2 of them (see nchoosek(n,2)),
            %   where n is number of unique labels in "y" array.  Each pairing will be
            %   returned in a separate row or "auc" and output "labels" will identify
            %   the pair of labels.
            %
            %   For multi-class AUC "Total AUC" as defined by Hand & Till (2001) can be
            %   calculated by mean(auc)}.
            %
            % See Also
            %  * http://en.wikipedia.org/wiki/Receiver_operating_characteristic
            %  * colAUC function from caTools package in R language
            %    http://cran.r-project.org/web/packages/caTools
            %
            % Written by Jarek Tuszynski, SAIC, jaroslaw.w.tuszynski_at_saic.com
            % Code covered by BSD License
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Adapted by JeanRémi King to:
            %   - accept below .5 results
            %   - compute n-class standard errors
            %   - deal with NaNs
            %   - reshape for n-dimensional matrix
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% make sure inputs are in correct format
            Plot = false;
            curve = [];
            if (nargin<4)  , alg  = 'Wilcoxon'; end
            if (nargout>=3)  , alg  = 'ROC'; end
            if (nargout==0), Plot = true; alg = 'ROC'; end
            switch alg
                case 'ROC'
                    alg = 'ROC';
                case {'Wilcoxon', 'Mann�Whitney U', 'Mann�Whitney�Wilcoxon', 'ranksum'}
                    alg = 'Wilcoxon';
                otherwise
                    alg = 'Wilcoxon';
            end
            %% Prepare for calculations & error-check
            y = y(:);
            na = size(X);
            [nr, nc] = size(X);                  % get dimentions of the data set
            [uL, ~, yy] = unique(y);             % find all the classes among the labels
            uL   = uL';
            nL   = length(uL);                   % number of unique classes
            if (nL<=1)
                warning('colAUC: List of labels ''y'' have to contain at least 2 class labels.')
                [auc labels curve se] = deal(NaN);
            else
                if (~isnumeric(X)), error('colAUC: ''X'' must be numeric'); end
                if (nr~=length(y)), error('colAUC: length(y) and size(X,1) must be the same'); end
                per  = nchoosek(1:nL,2);             % find all possible pairs of L columns
                labels = uL(per);                    % those are pairs of labels stored as strings
                if iscell(y)                         % unify label formats
                    labelStr = uL;                     % string version of the labels
                    y  = yy;
                    uL = 1:nL;                         % numeric version of the labels
                else
                    labelStr = cell(size(uL));         % string version of the labels
                    for i=1:nL, labelStr{i} = sprintf('%i',uL(i)); end
                end
                labelStr  = labelStr(per);           % those are pairs of labels stored as strings
                L        = uL(ones(nr,1),:);
                np       = size(per,1);              % how many possible pairs were found?
                auc      = zeros(np,nc)+0.5;         % Initialize array to store results
                rowLabel = cell(1,np);
                
                %% prepare the plot, if needed
                if (Plot)
                    clf;
                    hold on;
                    color = 'bgrmcyk';
                end
                
                switch alg
                    case 'ROC'
                        %% Calculate AUC by integrating ROC curves
                        iPlot = 0;
                        for c=1:nc                           % for each column representing a feature
                            inan = find(~isnan(X(:,c)));
                            [b, IDX] = sort( X(inan,c));          % sort all columns and store them in X. IDX stores original positions
                            nunq = find(diff(b)==0);           % find non-unique X's in column c (if vector is [1 1] nunq=1
                            nTies = length(nunq);              % number of non-unique values
                            if (nTies<nr-1)                    % make sure all numbers in X column are not the same
                                IDX = y(inan(IDX));            % reorder label vector in the same order as X, or associate label with each number in X
                                % assign column for each label (class) and for each point add 1 in the column corresponding to its class
                                d = ( IDX(:,ones(1,nL)) == L(inan,:));
                                d = cumsum(d,1);                 % cumulative sum of columns or left node counts per class for all possible thresholds
                                if(nTies), d(nunq, :) = []; end  % remove non unique rows (using nunq) if any
                                d = [ zeros(1, nL); d ];         %#ok<AGROW> % append row of zeros at the beggining
                                % assume that col#1 ploted on x axis is correct clasification and col#2 (y) is false find AUC
                                for i=1:np                       % go through all permutations of columns in d
                                    c1 = per(i,1);                 % and identify 2 classes to be compared
                                    c2 = per(i,2);
                                    n  = d(end,c1)*d(end,c2);      % normalize area to 1 at the maximum
                                    if (n>0)
                                        xx=d(:,c1)/d(end,c1);
                                        yy=d(:,c2)/d(end,c2);
                                        auc(i,c) = trapz(xx, yy);    % Trapezoidal numerical integration
                                    else
                                        xx=[0,1];
                                        yy=[0,1];
                                    end
                                    if (Plot)
                                        if (2*auc(i,c)<1), xx=1-xx; yy=1-yy; end % if auc<0.5 than mirror it to the other side of 0.5
                                        cc = mod(iPlot, length(color))+1;
                                        iPlot = iPlot+1;
                                        plot(xx, yy, color(cc));
                                        rowLabel{i} = sprintf('%s vs. %s', labelStr{i,1}, labelStr{i,2});
                                    end
                                    if nargout >= 3
                                        if c == 1
                                            curve = cell(nc,2);
                                        end
                                        curve{c,1} = xx;
                                        curve{c,2} = yy;
                                    end
                                end
                            end
                        end
                        
                    case 'Wilcoxon'
                        idxL = cell(1,nL);
                        for i = 1:nL, idxL{i} = find(y==uL(i)); end
                        for c = 1:nc                    % for each column representing a feature
                            for i = 1:np                  % go through all permutations of columns in d
                                c1 = per(i,1);              % and identify 2 classes to be compared
                                c2 = per(i,2);
                                inan = find(isnan(X([idxL{c1};idxL{c2}],c)));
                                x1 = X(setdiff(idxL{c1},inan),c);
                                x2 = X(setdiff(idxL{c2},inan),c);
                                n1 = length(x1);
                                n2 = length(x2);
                                if (n1>0 && n2>0)
                                    r = avrRank(app,[x1(:); x2(:)]);
                                    U = sum(r(1:n1)) - n1*(n1+1)/2; % Wilcoxon rank-sum test or Mann�Whitney U test
                                    auc(i,c) = U / (n1*n2);
                                end
                            end % end of 'for i' looplength(
                        end % end of 'for j' loop
                end
                % auc = 0.5 + abs(0.5-auc); % if any auc<0.5 than mirror it to the other side of 0.5 auc is a matrix
                
                %% estimate the area under the curve standard error of area using
                % Cardillo G. (2008) ROC curve: compute a Receiver Operating Characteristics curve.
                % http://www.mathworks.com/matlabcentral/fileexchange/19950
                idxL = cell(1,nL);
                for i = 1:nL, idxL{i} = find(y==uL(i)); end
                for i = 1:np
                    c1      = per(i,1);
                    c2      = per(i,2);
                    inan    = [];
                    for c = nc:-1:1
                        inan1(c) = sum(isnan(X(idxL{c1},c)));
                        inan2(c) = sum(isnan(X(idxL{c2},c)));
                    end
                    lu      = sum(y==c1)-inan1;
                    lh      = sum(y==c2)-inan2;
                    auc2    = auc(i,:).^2;
                    Q1      = auc(i,:)./(2-auc(i,:));
                    Q2      = 2*auc2./(1+auc(i,:));
                    V       = (auc(i,:).*(1-auc(i,:))+(lu-1).*(Q1-auc2)+(lh-1).*(Q2-auc2))./(lu.*lh);
                    se(i,:) = realsqrt(V);
                end
                
                %estimate the area under the curve
                %standard error of area
                % Area2=Area^2;
                % Q1=Area/(2-Area);
                % Q2=2*Area2/(1+Area);
                % V=(Area*(1-Area)+(lu-1)*(Q1-Area2)+(lh-1)*(Q2-Area2))/(lu*lh);
                % Serror=realsqrt(V);
                
                %% finalize the plot, if needed
                if (Plot)
                    %plot([0,1], [0,1], 'k')
                    xlabel({'probability of false alarm','(1-Specificity)'});
                    ylabel({'probability of detection'  ,'(Sensitivity)'});
                    xlim([0,1.01]);
                    ylim([0,1.01]);
                    title('ROC Curves');
                    grid on;
                    hold off;
                    if (nc==1 && np<20)  % if too many curves than skip the labels
                        legend(rowLabel, 'Location', 'SouthEast');
                    end
                end
                %% reshape AUC
                if length(na)>2
                    auc = reshape(auc,na(2:end));
                end
            end
            
        end
        
        function r = avrRank(app,x)
            %AVRRANK Returns the sample ranks of the values in a vector. In case of ties
            % (i.e., equal values) and missing values it computes their average rank.
            %
            % INPUT:
            %  x - any row or column vector, may be integer, character
            %      or floating point.
            %
            % OUTPUT:
            %  r - vector of corresponding ranks for each element in x tied elements
            %      are given an average rank for all tied elements.
            %
            % SEE ALSO:
            %  * rankt - package at
            %          http://www.mathworks.com/matlabcentral/fileexchange/27701-rankt
            %  * FractionalRankings from Rankings toolbox
            %          http://www.mathworks.com/matlabcentral/fileexchange/19496
            %  * tiedrank from MATLAB Statistics Toolbox
            %  * rank function in EXCEL
            %  * rank function in R language
            %
            % EXAMPLE:
            % x = round(rand(1,10)*5);
            % r = avrrank(app,x);
            % disp([x;r])
            %     3	  5    2	 1	 5	 0	 3	 2	 5	 0
            %    6.5 9.0	4.5	3.0	9.0	1.5	6.5	4.5	9.0	1.5
            %
            % Written by Jarek Tuszynski, SAIC, jaroslaw.w.tuszynski_at_saic.com
            % Code covered by BSD License
            
            %% Sort and create ranks array
            [sx, sortidx] = sort(x(:));
            nNaN  = sum(isnan(x));                 % number of NAN's in the array
            nx    = numel(x) - nNaN;               % length of x without NAN's
            ranks = [1:nx NaN(1,nNaN)]';           % push NAN's to the end
            
            %% Adjust for ties by averaging their ranks
            pos = 1;
            while pos<nx
                while (pos<nx && sx(pos)~=sx(pos+1)), pos=pos+1; end
                tieStart = pos;   % search until next tie is found
                while (pos<nx && sx(pos)==sx(pos+1)), pos=pos+1; end
                tieEnd   = pos;   % end of sequence of ties is found
                idx = tieStart : tieEnd;
                ranks(idx) = mean(ranks(idx)); % replace ranks with average ranks
            end
            
            %% Prepare output
            r(sortidx) = ranks;
            r = reshape(r,size(x));                % reshape to match input
            
        end
        
        function results = call_riemaniann(app, Xm, y, code, data_folder, cfg)
%             %
%             % results = call_riemaniann(Xm,y,cfg)
%             %
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             
%             % Implementation of a Riemaniann classification based on the
%             % scikitlearn toolbox (http://scikit-learn.org/stable/) and MNE-Python
%             % toolbox.
%             %
%             % It reads a matlab file containing :
%             % #     Xm:      a matrix of trials x chans x timepoint.
%             % #     y:       a vector indicating the class of each trial
%             %
%             % % Optional inputs
%             %
%             %
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if nargin == 5,                     cfg = [];                   end
            
            % get name;
            cfg.code = code;
            
            %-----------------------------
            
            if isfield(cfg,'pypath'),            cd(cfg.pypath);            end
            if ~isfield(cfg,'namey'),           cfg.namey = 'default';      end % classification names for outputs
            if ~isfield(cfg,'path'),            cfg.path = [pwd '/'];       end % classification path for output
            if ~isfield(cfg,'saveX'),           cfg.saveX= true;            end % save a new data set
            if ~isfield(cfg,'savey'),           cfg.savey= true;            end % save a classification parameters
            if ~isfield(cfg,'load_results'),    cfg.load_results= true;     end %
            if ~isfield(cfg,'run_svm'),         cfg.run_svm= true;          end % save a new data set
            if ~isfield(cfg,'Xformat'),         cfg.Xformat= '.dat';        end %
            if ~isfield(cfg,'void'),            cfg.void = true;            end % provide continuous feedback
            
            %-----------------------------
            
            if ~isfield(cfg,'n_jobs'), n_jobs = -1;
            else n_jobs = cfg.n_jobs;
            end
            
            %save('Xm.mat','Xm')
            save('Xm_test.mat','Xm','-mat') % '-v6','-append'
            %save('y.mat', 'y')
            save('y_test.mat', 'y','-mat') % ,'-v6','-append'
            %pathX=which('Xm.mat');
            pathX=which('Xm_test.mat');
            %pathy=which('y.mat');
            pathy=which('y_test.mat');
            %-----------------------------
            
            % save data
            if cfg.saveX && ~ischar(Xm) %|| ~exist(pathX, 'file')
                if cfg.void, disp(['save ' cfg.Xformat ': ' pathX '...']);end
                switch cfg.Xformat
                    case '.dat'
                        cfg.Xdim = size(Xm);
                        binsave(app, pathX, Xm);
                    case '.mat'
                        save(pathX, 'Xm');
                end
            end
            
            %-----------------------------
            
            if ischar(Xm)
                % default name identical to initial file
                
                if ~isfield(cfg,'nameX')
                    n = Xm;
                    p = strfind(n, '/');
                    if ~isempty(p), n = n((p(end)+1):end); end
                    p = strfind(n, cfg.Xformat);
                    if ~isempty(p), n = n(1:(p(end)-1)); end
                    cfg.nameX = n;
                end
                %pathX = Xm;
            else
                if ~isfield(cfg,'nameX'),        cfg.nameX = 'default';    end
                %pathX = [cfg.path cfg.nameX cfg.Xformat];
                cfg.Xdim = size(Xm);
            end
            
            %% RUN CLASSIFICATION
            
            % Create a Results folder in data folder
            results_path = data_folder;
            cd(results_path)
            mkdir('Results')
            results_path = strcat(results_path,'\Results\');
            
            % Get to application location
            app_path=mfilename('fullpath');
            app_path=app_path(1:end-27);
            app_path=strcat(app_path,'Scripts\');
            
            cd(app_path) %app folder
            script_path = which('riemaniann.py');
            
            if isunix
                disp('Launching Riemaniann Python Script')
                command = sprintf([...
                    'nice python "' script_path...
                    '" "' pathX ...
                    '" "' pathy ...
                    '" "' code ...
                    '" "' results_path ...
                    '" ' num2str(n_jobs)]);
            else
                disp('Launching Riemaniann Python Script')
                command = [...
                    'python "' script_path...
                    '" "' pathX ...
                    '" "' pathy ...
                    '" "' code ...
                    '" "' results_path ...
                    '" ' num2str(1)];
                warning('no parallel computing is supported with windows');
            end
            
            %-----------------------------
            
            if cfg.void
                disp(command);
            end
            save('riemaniann.mat');
            disp('All workspace variables are stored into "riemaniann.mat" file')

            %-----------------------------
            
            if cfg.run_svm
                if cfg.void
                    results=system(command);
                    disp('results : '); disp(results);
                else results=evalc('system(command)'); disp('results : '); disp(results); end
            end
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: ClickHereButton
        function ClickHereButtonPushed(app, event)
            winopen('Python Beginner Instructions.pdf')
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            cd(app.DataFolder)
            app.data=app.ListBox.Value;
            %             app.bads=app.BadchannelsmarkedwithEditField.Value;
            app.time_window=[app.fromEditField_2.Value app.toEditField.Value];
            app.condi_1=str2num(app.Condition1EditField.Value);
            app.condi_2=str2num(app.Condition2EditField.Value);
            app.step= app.StepEditField.Value;
            app.eye_channels=str2num(app.ChannelstoignoreEditField.Value);
            
            
            if app.SVMJRKButton.Value == true
    disp('JR decoding process LAUNCHED')
    app.decoding_type='JR____';
               
    
            else if app.XdawnRiemButton.Value == true
        disp('XDawn decoding process LAUNCHED')
        app.decoding_type='Xdawn_';
        
    else if app.ERPcovRiemButton.Value == true
            disp('ERPcov decoding process LAUNCHED')
            app.decoding_type='ERPcov';
            
        else if app.HenkelRiemButton.Value == true
                disp('Henkel decoding process LAUNCHED')
                app.decoding_type='Henkel';
                                
            end
        end
    end
            end
            
             [results, time2] = decoding_main(app, app.data, app.time_window, app.step, app.condi_1, app.condi_2, app.decoding_type, app.eye_channels, app.DataFolder);
    app.results = results;
    app.time2 = time2;
            save('unicog_app_data.mat')
            
            
        end

        % Value changed function: GeneralizationCheckBox
        function GeneralizationCheckBoxValueChanged(app, event)
            value = app.GeneralizationCheckBox.Value;
            if value == true
    % text fields
    app.Condition1genEditField.Editable = 'on';
    app.Condition2genEditField.Editable = 'on';
    app.Condition1genEditField.FontColor = [0.00 0.00 0.00];
    app.Condition2genEditField.FontColor = [0.00 0.00 0.00];
    app.Condition1genEditField.Value = '';
    app.Condition2genEditField.Value = '';
    % labels
    app.Condition1genLabel.FontColor = [0.00 0.00 0.00];
    app.Condition2genEditFieldLabel.FontColor = [0.00 0.00 0.00];
            else
    % text fields
    app.Condition1genEditField.Editable = 'off';
    app.Condition2genEditField.Editable = 'off';
    app.Condition1genEditField.FontColor = [0.651 0.651 0.651];
    app.Condition2genEditField.FontColor = [0.651 0.651 0.651];
    app.Condition1genEditField.Value = 'Select "generalization"';
    app.Condition2genEditField.Value = 'Select "generalization"';
    % labels
    app.Condition1genLabel.FontColor = [0.651 0.651 0.651];
    app.Condition2genEditFieldLabel.FontColor = [0.651 0.651 0.651];
            end
        end

        % Button pushed function: LaunchEEGlabButton
        function LaunchEEGlabButtonPushed(app, event)
            eeglab
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            Data = uigetdir;
            app.DataFolder = Data;
            app.FolderPathTxt.Text = app.DataFolder;
            cd(app.DataFolder)
            app.Set_files = dir('*.set');
            app.file_list=[];
            
            for i = 1:length(app.Set_files)
    app.file_list{i,1}=char(app.Set_files(i).name);
            end
            
            app.ListBox.Items = app.file_list;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UNIdecodLabel = uilabel(app.UIFigure);
            app.UNIdecodLabel.Text = 'UNIdecod';
            app.LoadFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Data folder...';
            app.SelectonefileormoreLabel = uilabel(app.UIFigure);
            app.SelectonefileormoreLabel.Text = 'Select one file or more :';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Please select folder'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Please select folder'};
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.FolderPathTxt = uilabel(app.UIFigure);
            app.FolderPathTxt.Text = '[ ]';
            app.TimewindowLabel = uilabel(app.UIFigure);
            app.TimewindowLabel.Text = 'Time window  :';
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Value = 1000;
            app.fromEditField_2Label = uilabel(app.UIFigure);
            app.fromEditField_2Label.Text = 'from';
            app.fromEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.fromEditField_2.Value = -200;
            app.InformationLabel = uilabel(app.UIFigure);
            app.InformationLabel.Text = {'Information : '; ''; 'This MATLAB application is an interactive decoding tool running Python decoding'; 'scripts. '; ''; 'In order to use the decoding functions of this application, you need to install Python'; 'and all the necessary packages.'; ''; 'For more Information : '};
            app.DecodingButtonGroup = uibuttongroup(app.UIFigure);
            app.DecodingButtonGroup.Title = 'Decoding :';
            app.SVMJRKButton = uiradiobutton(app.DecodingButtonGroup);
            app.SVMJRKButton.Text = 'SVM (JRK)';
            app.SVMJRKButton.Value = true;
            app.XdawnRiemButton = uiradiobutton(app.DecodingButtonGroup);
            app.XdawnRiemButton.Text = 'Xdawn (Riem.)';
            app.ERPcovRiemButton = uiradiobutton(app.DecodingButtonGroup);
            app.ERPcovRiemButton.Text = 'ERPcov (Riem.)';
            app.HenkelRiemButton = uiradiobutton(app.DecodingButtonGroup);
            app.HenkelRiemButton.Text = 'Henkel (Riem.)';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step :';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField.Value = 1;
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'ms.';
            app.ClickHereButton = uibutton(app.UIFigure, 'push');
            app.ClickHereButton.ButtonPushedFcn = createCallbackFcn(app, @ClickHereButtonPushed, true);
            app.ClickHereButton.Text = 'Click Here ';
            app.ChannelstoignoreLabel = uilabel(app.UIFigure);
            app.ChannelstoignoreLabel.Text = 'Channels to ignore :';
            app.ChannelstoignoreEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelstoignoreEditField.Value = '125,126,127,128';
            app.Condition1Label = uilabel(app.UIFigure);
            app.Condition1Label.Text = 'Condition 1 :';
            app.Condition1EditField = uieditfield(app.UIFigure, 'text');
            app.Condition1EditField.Value = '5,3';
            app.Condition2Label = uilabel(app.UIFigure);
            app.Condition2Label.Text = 'Condition 2 :';
            app.Condition2EditField = uieditfield(app.UIFigure, 'text');
            app.Condition2EditField.Value = '1,6';
            app.exampleCondition11Condition224Label = uilabel(app.UIFigure);
            app.exampleCondition11Condition224Label.Text = {'example :'; 'Condition 1 : 1'; 'Condition 2 : 2,4'};
            app.GeneralizationCheckBox = uicheckbox(app.UIFigure);
            app.GeneralizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeneralizationCheckBoxValueChanged, true);
            app.GeneralizationCheckBox.Text = 'Generalization';
            app.Condition1genLabel = uilabel(app.UIFigure);
            app.Condition1genLabel.Text = 'Condition 1 (gen) :';
            app.Condition1genEditField = uieditfield(app.UIFigure, 'text');
            app.Condition1genEditField.Editable = 'off';
            app.Condition1genEditField.Value = 'Select ''generalization''';
            app.Condition2genEditFieldLabel = uilabel(app.UIFigure);
            app.Condition2genEditFieldLabel.Text = 'Condition 2 (gen) :';
            app.Condition2genEditField = uieditfield(app.UIFigure, 'text');
            app.Condition2genEditField.Editable = 'off';
            app.Condition2genEditField.Value = 'Select ''generalization''';
            app.LaunchEEGlabButton = uibutton(app.UIFigure, 'push');
            app.LaunchEEGlabButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchEEGlabButtonPushed, true);
            app.LaunchEEGlabButton.Text = 'Launch EEGlab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UNIdecod_with_function_temp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end